classdef Addcell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddcellUI              matlab.ui.Figure
        Image                  matlab.ui.container.Panel
        Panel                  matlab.ui.container.Panel
        Display                matlab.ui.container.Panel
        ContrastSliderLabel    matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        SegBButton             matlab.ui.control.StateButton
        BrightnessSliderLabel  matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        DisplayLabel           matlab.ui.control.Label
        AppliedSegButton       matlab.ui.control.StateButton
        DAPIButton             matlab.ui.control.StateButton
        MemButton              matlab.ui.control.StateButton
        ShowMemCheckBox        matlab.ui.control.CheckBox
        SegAButton             matlab.ui.control.StateButton
        AddanothercellButton   matlab.ui.control.StateButton
        DrawButton             matlab.ui.control.StateButton
        AcceptButton           matlab.ui.control.StateButton
        RejectButton           matlab.ui.control.StateButton
    end

    
    properties (Access = private)
         CallingApp % main app object
        %% -----------------------------------------------
        % for image display
        % format
        mycols = [0 0 1; % DAPI
            1 1 1];   % Membrane
        a_m = 2.5; % default values for the colors
        a_d = 2.5;
        b_m = 200;
        b_d = 200;
        overlap_table2
        draw_im2
        applied_seg_im2
        cell_obj;
        C1_Image
        brd_ii;
        cell_idx;
        seg_col = 200;
        e;
        n;
        n_count;
    end
    
    methods (Access = private)
        %
        function disp_images(app, nm)
            %%
            % calls the image display for each image and renames if nm == 1
            %%
            if nm
                ff = app.CallingApp.fname;
                app.AddcellUI.Name = [ff, '; Currently adding ', ...
                    num2str(app.n_count),' cell(s)'];
                xl = [1, app.CallingApp.rr - app.CallingApp.ll];
                yl = [1, app.CallingApp.bb - app.CallingApp.tt];
                %
            else
                xl = xlim(app.C1_Image);
                yl = ylim(app.C1_Image);
            end
            %
            imshow3(app, xl, yl)
            %
        end
        %
        function imshow3(app,xl,yl)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            % xl: x centroid
            % yl: y centroid
            %%
            C = app.CallingApp.nCols;
            R = app.CallingApp.nRows;
            %
            if app.ShowMemCheckBox.Value
                im = app.CallingApp.im_m;
            else
                im = app.CallingApp.im_d;
            end
            %
            im = reshape(im, R, C, 3);
            im = im(app.CallingApp.tt(1):app.CallingApp.bb(1),...
                app.CallingApp.ll(1):app.CallingApp.rr(1),:);
            %
            % default is for segmentation to be on
            %
            if app.SegBButton.Value || ...
                    app.AppliedSegButton.Value || ...
                    app.SegAButton.Value
                %
                if app.SegBButton.Value
                    %
                    if app.CallingApp.B == 1
                        seg = app.CallingApp.seg_IF;
                    else
                        seg = app.CallingApp.seg_SP;
                    end
                    %
                elseif app.SegAButton.Value
                    %
                    if app.CallingApp.A == 1
                        seg = app.CallingApp.seg_IF;
                    else
                        seg = app.CallingApp.seg_SP;
                    end
                    %
                elseif app.AppliedSegButton.Value
                    %
                    seg = app.applied_seg_im2;
                    seg = buildSegMask(app, seg);
                    %
                end
                %
                ii = seg > 0;
                aa = .35;
                if length(ii) > 1
                    im(ii) = (1-aa) .* im(ii) ...
                        + aa.* double(seg(ii));
                end
                %
            end
            %
            im = uint8(im);
            %
            imshow(im, 'Parent', app.C1_Image);
            xlim(app.C1_Image, xl)
            ylim(app.C1_Image, yl)
            %
        end
        %       
        function apply_image_colors(app, dapi_logical, mem_logical)
            %%
            % scale the colors according to the slider and color
            % button selection
            %%
            %
            if dapi_logical
                app.a_d = .05 * app.ContrastSlider.Value;
                app.b_d = 4 * app.BrightnessSlider.Value;
                app.CallingApp.im_d =  app.b_d .* asinh(...
                    app.a_d .* app.CallingApp.mem_dapi_im(:,1))...
                    * app.mycols(1,:);
            end
            %
            if mem_logical
                app.a_m = .05 * app.ContrastSlider.Value;
                app.b_m = 4 * app.BrightnessSlider.Value;
            end
            %
            app.CallingApp.im_m =  app.b_m .* asinh(...
                app.a_m .* app.CallingApp.mem_dapi_im(:,2))...
                * app.mycols(2,:);
            app.CallingApp.im_m = app.CallingApp.im_m + app.CallingApp.im_d;
            %
        end
        %
        function im_s = buildSegMask(app, im_s)
            %%
            % build the segmentation mask for the given vars
            %%
            im_s = im_s(app.CallingApp.tt(1):app.CallingApp.bb(1),...
                app.CallingApp.ll(1):app.CallingApp.rr(1),1);
            s1 = size(im_s);
            im_s = cat(3,im_s, zeros(s1(1),s1(2),2));
            %
        end
        %
        function chgColors(~,hnd,step)
            %
            switch step
                case 'on'
                    hnd.BackgroundColor = 'g';
                    hnd.Value = 1;
                case 'off'
                    hnd.BackgroundColor = [.90 .90 .90];
                    hnd.Value = 0;
            end
            %
        end
        %
        function rstAllButtons(app, t)
            robot = java.awt.Robot;
            robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
            robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
            if t == 1
                chgColors(app,app.SegBButton,'on')
                app.SegBButton.Value = 1;
                chgColors(app,app.AppliedSegButton,'off')
                chgColors(app,app.SegAButton,'off')
                chgColors(app,app.DAPIButton,'off')
                chgColors(app,app.MemButton,'off')
            end
            %
            if (t == 2 ||t == 3 || t == 4) && ~isempty(app.n)
                app.overlap_table2.class_selection(app.cell_idx) = 0;
                if ~isempty(app.brd_ii)
                    app.applied_seg_im2(app.brd_ii) = 0;
                    id = app.overlap_table2.pairid(app.cell_idx);
                    ii = app.draw_im2  == id;
                    app.draw_im2 (ii) = 0;
                end
                %
                app.brd_ii = [];
                if ~isempty(app.cell_obj)
                    delete(app.cell_obj)
                end
                %
                if (app.AppliedSegButton.Value && t == 3) || t == 4
                    disp_images(app, 1);
                end
                %
            end
            %
        end
        %
        function draw_obj(app, loc, inp)
            %%
            % draw the cell object
            %%
            app.e = 0;
            if ~isempty(app.cell_obj)
                delete(app.cell_obj)
            end
            %
            pause(.5)
            app.cell_obj = drawfreehand(...
                loc, 'InteractionsAllowed','none',...
                'FaceAlpha', .2 , 'Multiclick', true);
            pause(.5)
            try
                mm = createMask(app.cell_obj);
            catch
                if ~isempty(app.cell_obj)
                    delete(app.cell_obj)
                end
                app.e = 1;
                return
            end
            %
            % map back to the image
            %
            [row, col] = find(mm > 0);
            row2 = row + app.CallingApp.tt(1) - 1;
            col2 = col + app.CallingApp.ll(1) - 1;
            idx = sub2ind([app.CallingApp.nRows,...
                app.CallingApp.nCols],row2,col2);
            app.draw_im2 (idx) = inp;
            %
            [row, col] = find(imdilate(mm,ones(3,3)) - mm);
            row2 = row + app.CallingApp.tt(1) - 1;
            col2 = col + app.CallingApp.ll(1) - 1;
            app.brd_ii = sub2ind([app.CallingApp.nRows,...
                app.CallingApp.nCols],row2,col2);
            app.applied_seg_im2(app.brd_ii) = app.seg_col;
            %
        end
        %        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupAddcell(app, mainapp)
            %
            % recieve variables from main app
            %
            app.CallingApp = mainapp;  
            app.overlap_table2 = app.CallingApp.overlap_table;
            app.applied_seg_im2 = app.CallingApp.applied_seg_im;
            app.draw_im2 = app.CallingApp.draw_im;
            %
            % Configure image axes
            %
            app.C1_Image = axes('Parent',app.Image);
            app.C1_Image.Box = 'on';
            app.C1_Image.XTick = [];
            app.C1_Image.XTickLabel = [];
            app.C1_Image.YTick = [];
            app.C1_Image.ZTick = [];
            app.C1_Image.Color = [1 1 1];
            app.C1_Image.Units = 'pixels';
            app.C1_Image.Position = [-45 10 380 275];
            app.C1_Image.Visible = 'on';
            app.C1_Image.Colormap = gray(256);
            axis(app.C1_Image, 'image');
            %
            app.n_count = 1;
            disp_images(app,1)
            id = height(app.overlap_table2) + 1;
            %
            tmp = array2table(zeros(1, width(app.overlap_table2)));
            tmp.Properties.VariableNames ...
                = app.overlap_table2.Properties.VariableNames;
            app.overlap_table2 = [app.overlap_table2;tmp];
            % 
            app.overlap_table2.pairid(id) = id;
            app.cell_idx = id;
            %
            inp = max(app.overlap_table2.counter) + 1;
            app.n = inp;
            app.overlap_table2.counter(id) = inp;
            %
            app.overlap_table2.cell_check(id) = 1;
            app.overlap_table2.class_selection(id) = 3;
            app.overlap_table2.flags(id) = 0;
            %
            if app.CallingApp.addcell_instr == 0
                msg = ['This is the first time "Add cell" has ',...
                    'been selected in this session. Here are some ',...
                    'hints to help you get started: ',...
                    'Select the "Draw" button and wait for it ',...
                    'to turn green to begin drawing. ',...
                    'If you wish to add another cell select ',...
                    '"Add another cell" and wait for that button to ',...
                    'turn green before drawing. You may add as many ',...
                    'new cells as you like, a tally is recorded in ',...
                    'the figure header. If you wish to redraw a cell ',...
                    'simply close the cell and reselect the "Draw" ',...
                    'button. You may either "Accept" or "Reject" all ',...
                    'cells at any time using the corresponding buttons',...
                    'After selecting the "Reject" button, there is ',...
                    'also the option to reject only the last cell ',...
                    'drawn if you have drawn more than one.'];
                uialert(app.AddcellUI, msg,'Helpful hints',...
                    'Icon','info')
            end
            %
            %draw_obj(app, app.C1_Image, id)
            %
        end

        % Value changed function: MemButton
        function MemButtonValueChanged(app, event)
            %
            if app.MemButton.Value
                chgColors(app,app.DAPIButton,'off')
                chgColors(app,app.MemButton,'on')
                app.ContrastSlider.Value = app.a_m /.05;
                app.BrightnessSlider.Value = app.b_m / 4;
            else
                chgColors(app,app.MemButton,'off')
            end
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            if app.DAPIButton.Value
                chgColors(app,app.MemButton,'off')
                chgColors(app,app.DAPIButton,'on')
                app.ContrastSlider.Value = app.a_d /.05;
                app.BrightnessSlider.Value = app.b_d / 4;
            else
                chgColors(app,app.DAPIButton,'off')
            end
            %        
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %
            if ~isempty(app.overlap_table2)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(...
                    app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %
            if ~isempty(app.overlap_table2)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: SegAButton
        function SegAButtonValueChanged(app, event)
            %
            if app.SegAButton.Value
                chgColors(app,app.SegBButton,'off')
                chgColors(app,app.AppliedSegButton,'off')
                chgColors(app,app.SegAButton,'on')
            else
                chgColors(app,app.SegAButton,'off')
            end
            %
            if ~isempty(app.overlap_table2)   
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: SegBButton
        function SegBButtonValueChanged(app, event)
            %
            if app.SegBButton.Value
                chgColors(app,app.AppliedSegButton,'off')
                chgColors(app,app.SegAButton,'off')
                chgColors(app,app.SegBButton,'on')
            else
                chgColors(app,app.SegBButton,'off')
            end
            %
            if ~isempty(app.overlap_table2)    
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: AppliedSegButton
        function AppliedSegButtonValueChanged(app, event)
            %
            if app.AppliedSegButton.Value
                chgColors(app,app.SegBButton,'off')
                chgColors(app,app.SegAButton,'off')
                chgColors(app,app.AppliedSegButton,'on')
            else
                chgColors(app,app.AppliedSegButton,'off')
            end
            %
            if ~isempty(app.overlap_table2)   
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: ShowMemCheckBox
        function ShowMemCheckBoxValueChanged(app, event)
            %
            if ~isempty(app.overlap_table2)   
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: DrawButton
        function DrawButtonValueChanged(app, event)
            %
            if app.DrawButton.Value
                %
                % reset all pushed buttons for classification, set 
                % DrawonDAPI to 'on'
                %
                rstAllButtons(app, 4)
                chgColors(app,app.DrawButton, 'on')
                %
                % update table
                %
                if ~isempty(app.n)
                    %
                    inp = app.cell_idx;
                    app.overlap_table2.class_selection(inp) = 3;
                    id = app.overlap_table2.pairid(inp);
                    %
                    % draw object
                    %
                    draw_obj(app, app.C1_Image, id)
                    if app.e == 1
                        uialert(app.AddcelllUI,...
                            ['Cell did not close correctly ',...
                            'and will not be saved. Please try again.'],...
                            'Warning','Icon', 'warning')
                        rstAllButtons(app, 4)
                    end
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                    chgColors(app,app.DrawButton, 'off')
                    %
                end
                %
            else
                chgColors(app,app.DrawButton, 'off')
                rstAllButtons(app, 4)
                %
                robot = java.awt.Robot;
                robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                %
            end
            %
        end

        % Value changed function: RejectButton
        function RejectButtonValueChanged(app, event)
            chgColors(app,app.RejectButton, 'on');
            %
            if app.n_count == 1
                %
                % delete the app do not return anything
                %
                msg = ['Reject any drawn cell(s) and return to main application? ',...
                    '(to redraw, select "cancel" then reselect the "Draw" button).'];
                title = 'Confirm "Reject"';
                del_var = uiconfirm(app.AddcellUI,msg,title,...
                    'Options',{'Confirm','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                if strcmp(del_var, 'Confirm')
                    delete(app)
                else
                    chgColors(app,app.RejectButton, 'off');
                end
                %
            else
                %
                % delete just the most 
                % recently drawn or  delete all and leave back to other UI
                %
                msg = ['Reject all cells drawn ',...
                       'and return to main application? ',...
                        'Or reject last cell drawn and ',...
                        'return to main application? ',...
                        '(to redraw last cell, select "cancel" ',...
                        'then reselect the "Draw" button).'];
                title = 'Confirm "Reject"';
                del_var = uiconfirm(app.AddcellUI,msg,title,...
                    'Options',{'Reject All','Reject last cell','Cancel'},...
                    'DefaultOption',2,'CancelOption',3);
                
                if strcmp(del_var, 'Reject All')
                    %
                    % delete the app do not return anything
                    %
                    delete(app)
                    %
                elseif strcmp(del_var, 'Reject last cell')
                    %
                    % delete only the current values
                    %
                    app.n_count = app.n_count - 1;
                    rstAllButtons(app, 4);
                    app.overlap_table2(app.cell_idx,:) = [];
                    returnAddcell(app.CallingApp, app.overlap_table2, ...
                        app.draw_im2, app.applied_seg_im2)
                    delete(app)
                    %{
                elseif del_var == 4
                    %
                    % reset the table and counters to intial vals
                    %
                    app.overlap_table2 = app.CallingApp.overlap_table;
                    app.applied_seg_im2 = app.CallingApp.applied_seg_im;
                    app.draw_im2 = app.CallingApp.draw_im;
                    %
                    id = height(app.overlap_table2) + 1;
                    app.overlap_table2.pairid(id) = id;
                    app.cell_idx = id;
                    %
                    inp = max(app.overlap_table2.counter) + 1;
                    app.n = inp;
                    app.overlap_table2.counter(id) = inp;
                    %
                    app.overlap_table2.cell_check(id) = 1;
                    app.overlap_table2.class_selection(id) = 3;
                    app.overlap_table2.flags(id) = 0;
                    %
                    app.n_count = 1;
                    disp_images(app,1)
                    %}
                else
                %
                % in case of cancel
                %
                chgColors(app,app.RejectButton, 'off');
                end
            end
            %
        end

        % Value changed function: AcceptButton
        function AcceptButtonValueChanged(app, event)
            %
            chgColors(app,app.AcceptButton, 'on');
            msg = 'Accept drawn cell segmentation and close?';
            title = 'Confirm "Accept"';
            selection = uiconfirm(app.AddcellUI,msg,title);
            if strcmp(selection,'OK')
                returnAddcell(app.CallingApp, app.overlap_table2, ...
                    app.draw_im2, app.applied_seg_im2)
                delete(app)
            else
                chgColors(app,app.AcceptButton, 'off');
            end
            %
        end

        % Value changed function: AddanothercellButton
        function AddanothercellButtonValueChanged(app, event)
            if app.AddanothercellButton.Value
                chgColors(app,app.AddanothercellButton, 'on');
                %
                app.n_count = app.n_count + 1;
                disp_images(app,1)
                id = height(app.overlap_table2) + 1;
                %
                tmp = array2table(zeros(1, width(app.overlap_table2)));
                tmp.Properties.VariableNames ...
                    = app.overlap_table2.Properties.VariableNames;
                app.overlap_table2 = [app.overlap_table2;tmp];
                %
                app.overlap_table2.pairid(id) = id;
                app.cell_idx = id;
                %
                inp = max(app.overlap_table2.counter) + 1;
                app.n = inp;
                app.overlap_table2.counter(id) = inp;
                %
                app.overlap_table2.cell_check(id) = 1;
                app.overlap_table2.class_selection(id) = 3;
                app.overlap_table2.flags(id) = 0;
                %
                draw_obj(app, app.C1_Image, id)
                %
                chgColors(app,app.AddanothercellButton, 'off');
            else
                app.n_count = app.n_count - 1;
                rstAllButtons(app, 4);
                app.overlap_table2(app.cell_idx,:) = [];
                %
                robot = java.awt.Robot;
                robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                %
                chgColors(app,app.AddanothercellButton, 'off'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddcellUI = uifigure('Visible', 'off');
            app.AddcellUI.Name = 'MATLAB App';
            app.Image = uipanel(app.AddcellUI);
            app.Panel = uipanel(app.AddcellUI);
            app.Display = uipanel(app.Panel);
            app.ContrastSliderLabel = uilabel(app.Display);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Display);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSlider.Value = 50;
            app.SegBButton = uibutton(app.Display, 'state');
            app.SegBButton.ValueChangedFcn = createCallbackFcn(app, @SegBButtonValueChanged, true);
            app.SegBButton.Text = 'Seg B';
            app.BrightnessSliderLabel = uilabel(app.Display);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Display);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Value = 50;
            app.DisplayLabel = uilabel(app.Display);
            app.DisplayLabel.Text = ' Display';
            app.AppliedSegButton = uibutton(app.Display, 'state');
            app.AppliedSegButton.ValueChangedFcn = createCallbackFcn(app, @AppliedSegButtonValueChanged, true);
            app.AppliedSegButton.Text = 'Applied Seg';
            app.DAPIButton = uibutton(app.Display, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Text = 'DAPI';
            app.MemButton = uibutton(app.Display, 'state');
            app.MemButton.ValueChangedFcn = createCallbackFcn(app, @MemButtonValueChanged, true);
            app.MemButton.Text = 'Mem';
            app.ShowMemCheckBox = uicheckbox(app.Display);
            app.ShowMemCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMemCheckBoxValueChanged, true);
            app.ShowMemCheckBox.Text = 'Show Mem';
            app.SegAButton = uibutton(app.Display, 'state');
            app.SegAButton.ValueChangedFcn = createCallbackFcn(app, @SegAButtonValueChanged, true);
            app.SegAButton.Text = 'Seg A';
            app.AddanothercellButton = uibutton(app.Panel, 'state');
            app.AddanothercellButton.ValueChangedFcn = createCallbackFcn(app, @AddanothercellButtonValueChanged, true);
            app.AddanothercellButton.Text = 'Add another cell';
            app.DrawButton = uibutton(app.Panel, 'state');
            app.DrawButton.ValueChangedFcn = createCallbackFcn(app, @DrawButtonValueChanged, true);
            app.DrawButton.Text = 'Draw';
            app.AcceptButton = uibutton(app.Panel, 'state');
            app.AcceptButton.ValueChangedFcn = createCallbackFcn(app, @AcceptButtonValueChanged, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.Panel, 'state');
            app.RejectButton.ValueChangedFcn = createCallbackFcn(app, @RejectButtonValueChanged, true);
            app.RejectButton.Text = 'Reject';
            % Show the figure after all components are created
            app.AddcellUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Addcell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddcellUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupAddcell(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddcellUI)
        end
    end
end