classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ALabel                 matlab.ui.control.Label
        BLabel                 matlab.ui.control.Label
        Selections             matlab.ui.container.Panel
        backOnecellButton      matlab.ui.control.Button
        NextCellButton         matlab.ui.control.Button
        JumptocellButton       matlab.ui.control.Button
        JumpCellButton         matlab.ui.control.NumericEditField
        forwardOnecellButton   matlab.ui.control.Button
        Panel_5                matlab.ui.container.Panel
        ABButton               matlab.ui.control.Button
        AButton                matlab.ui.control.Button
        BButton                matlab.ui.control.Button
        NeitherButton          matlab.ui.control.Button
        ArtifactButton         matlab.ui.control.Button
        DrawonDAPIButton       matlab.ui.control.Button
        DrawonMemButton        matlab.ui.control.Button
        ClassificationLabel    matlab.ui.control.Label
        FlagButton             matlab.ui.control.Button
        MultiNucButton         matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Display                matlab.ui.container.Panel
        ContrastSliderLabel    matlab.ui.control.Label
        ToggleSegButton        matlab.ui.control.StateButton
        ContrastSlider         matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        DisplayLabel           matlab.ui.control.Label
        AppliedSegButton       matlab.ui.control.StateButton
        DAPIButton             matlab.ui.control.StateButton
        MemButton              matlab.ui.control.StateButton
        LoadButton             matlab.ui.control.Button
        SaveTableButton        matlab.ui.control.Button
        Images                 matlab.ui.container.Panel
    end

    %
    properties (Access = public)
        im_ML % Machine learning label matrix
        im_IF % inForm label matrix
        nRows % rows
        nCols % columns
        mem_dapi_im % dapi and membrane component layers in column vector format
        mycols = [0 0 1; % DAPI
                 1 1 1];   % Membrane
       wd % directory with the inform and ML_data subfolders in it
       fname % current image name
       a_m = 2.5; % 
       a_d = 2.5;
       b_m = 200;
       b_d = 200;
       IF_cells % cell array of cells in inForm label matrix
       ML_cells % cell array of cells in ML label matrix
       m % temporary centroid or midpoint variable passed between 
       % functions, created from label matrix 
       IF_table % intial table with all inForm cells in it
       ML_table %intial table with all machine learning cells in it
       cells_n % cells variable passed between functions
       q % temporary table
       overlap_table % final\ resulting table with overlaps and other 
       % relevant info
       o_tbl_IF % inform overlap table 
       o_tbl_ML % machine learning overlap table
       n % current cell row in the overlap table for analysis
       A_Image % the dapi machine learning image
       A_Image_2 % the membrane and dapi machine learning image
       B_Image % the dapi inform image
       B_Image_2 % the membrane and dapi inform image
       cell_obj % the cell object created when drawing a given cell
       rr % the right side index of the image for a given cell cut out
       ll % the left side index of the image for a given cell cut out
       bb % the bottom side index of the image for a given cell cut out
       tt % the top side index of the image for a given cell cut out
       seg_IF % segmentation overlay image for IF
       seg_ML % segmentation overlay image for ML
       IF_c % current cell coordinates for IF
       ML_c % current cell coordinates for ML
       im_d % dapie image 
       im_m % membreane image
       cc % value that tells the next cell button whether or to set 
       % ML default in table
       e % error variable passed between functions
       IF_filename % the inform file name
       ML_filename % superpixel file name
       pgbar % progress bar passed between apps for the image loading
       hh2
       %
    end
    %
    properties (Access = private)
        ClosingDialogApp % closing app dialog
        closing_opts % 1 to close the UI, 0 to leave is open
    end
    %
    methods (Access = private)
        %
        function updateimage(app,imagefile)
            %
            parse_image_filename(app, imagefile)
            %
            switch app.e
                case  1
                    uialert(app.UIFigure,['Image not loaded: ',....
                        'IF path: ', app.IF_filename,' does not exist.',...
                        'Check file structure.'],...
                         "Image Path Error");
                    return
                case 2
                    uialert(app.UIFigure,['Image not loaded: ',....
                        'ML path: ', app.ML_filename,' does not exist.',...
                        'Check file structure.'],...
                         "Image Path Error");
                    return
                case 3
                    uialert(app.UIFigure,['Image not loaded: ',....
                        'input path must contain either',...
                        '"superpixel" or "inform_data".'],...
                         "Image Path Error");
                    return
                case 4
                    uialert(app.UIFigure,['Image not loaded: ',....
                        'input path must contain either',...
                        '"superpixel" or "inform_data" type data.'],...
                         "Image Path Error");
                    return
            end  
            %
            readimages(app)
            %
            switch app.e
                case 1
                    uialert(app.UIFigure,...
                        'inForm Image Error: MISSING IMAGE LAYERS.',...
                        "Loading Image Error");
                    return 
                case 2
                    uialert(app.UIFigure,...
                        ['inForm Image Error: check path',...
                        app.IF_filename,'.'], "Loading Image Error");
                    return
                case 3
                    uialert(app.UIFigure,...
                        ['Superpixel Image Error: check path',...
                        app.ML_filename,'.'], "Loading Image Error");
                    return
            end
            % 
            % read in or create the table
            %
            app.pgbar.Value = .60;
            app.pgbar.Message = 'Checking for previous comparison files.';
            pause(.5)
            comp_p_file_IF = [app.wd,'\inform_data\Component_Tiffs\',...
                app.fname,'_comparison_seg_data.csv'];
            %
            if exist(comp_p_file_IF,'file')
                reload_overlap_table(app)
            else
                compute_pairs(app)
            end
            %
            % find correct cell
            %
            app.n = find(app.overlap_table.class_selection == 0, 1);
            %
            apply_image_colors(app, 1, 1)
            getSegmap(app)
            disp_images(app,1,app.n,height(app.overlap_table))
            %
        end
        %
        function parse_image_filename(app, imagefile)
            %%
            % this function parses the input image filename to get both the
            % superpixel and the inform data images
            %
            %%
            fn = extractBetween(...
                imagefile,'Component_Tiffs\','_component');
            if isempty(fn)
                app.e = 4;
                return
            end
            app.fname = fn{1};
            app.e = 0;
            %
            % if input name is in superpixel tree
            %
            if contains(imagefile, 'superpixel')
                app.wd = extractBefore(...
                    imagefile, ['\','superpixel','\Component_Tiffs']);
                app.ML_filename = imagefile;
                imagefile2 = replace(imagefile,...
                    'superpixel', 'inform_data');
                app.IF_filename = replace(imagefile2,'_seg','_w_seg');
                if ~isfile(app.IF_filename)
                    app.e = 1;
                    return
                end
                %
                % if input name is in the inform tree
                %
            elseif contains(imagefile, 'inform_data')
                app.wd = extractBefore(...
                    imagefile, ['\','inform_data','\Component_Tiffs']);
                app.IF_filename = imagefile;
                imagefile2 = replace(imagefile,...
                    'inform_data','superpixel');
                app.ML_filename = replace(imagefile2,'_w_seg','_seg');
                if ~isfile(app.ML_filename)
                    app.e = 2;
                    return
                end
                %
                % if input name is not in the proper structure
                %
            else
                app.e = 3;
                return
            end
            %
        end
        %
        function readimages(app)
            %%
            % this function reads in the images and gather necessary
            % metadata along the way
            %
            %%
            app.pgbar.Value = .15;
            app.pgbar.Message = 'Getting image file metadata';
            pause(.5)
            %
            % get the inform image number of layers and dims
            %
            app.e = 0;
            try
                im_info = imfinfo(app.IF_filename);
            catch
                app.e = 2;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
                app.e = 1;
                return;
            end
            %
            app.pgbar.Value = .20;
            app.pgbar.Message = 'Reading InForm image layers into memory.';
            pause(.5)
            %
            % read INFORM IMAGE
            %
            try
                ic = 0;
                app.im_ML = zeros(app.nRows, app.nCols, 5);
                for i1 = 11:15
                    ic = ic + 1;
                    app.im_IF(:,:,ic) = imread(app.IF_filename,i1);
                end
                %
                ic = 0;
                app.mem_dapi_im = zeros(app.nRows * app.nCols, 2);
                for i1 = [1, 9]
                    im = imread(app.IF_filename, i1);
                    im = im ./ max(im,[],'all');
                    ic = ic + 1;
                    app.mem_dapi_im(:,ic) = reshape(im, [], 1);
                end
                %
            catch
                app.e = 2;
            end
            %
            % read the superpixel image; we denote this as ML in the code
            % for 'machine learning'
            %
            app.pgbar.Value = .40;
            app.pgbar.Message =...
                'Reading superpixel image layers into memory.';
            pause(.5)
            %
            try
                app.im_ML = zeros(app.nRows, app.nCols, 2);
                for i1 = 1:2
                    app.im_ML(:,:,i1) = imread(app.ML_filename, i1);
                end
            catch
                app.e = 3;
            end
        end
        %
        function compute_pairs(app)
            %%
            %
            % this is where the tables are computed for cells and cell 
            % overlap pct is assigned
            %
            %%
            app.pgbar.Value = .61;
            app.pgbar.Message = ['Previous comparison files not ',...
                'found ... computing new comparison results'];
            pause(.5)
            %
            % ML cell table
            %
            app.cells_n = label2idx(app.im_ML)';
            im = app.im_ML(:,:,1);
            m1 = regionprops(im,'centroid');
            app.m = struct2cell(m1);
            crt_int_tbl(app, 'ML');
            %
            % get distinct objects in inform
            %
            getIFdistinct(app);
            %
            % create the IF table
            %
            crt_int_tbl(app, 'IF');
            %
            % get the cells which pixels overlap with ML
            %
            comp_overlap(app);
            %
            % add non overlapping cells
            %
            add_no(app);
            %
            % remove edge cells, get linear indicies of all boarder pixels
            %
            rm_edge_cells(app);
            %
            % intialize the final results columns and for 80% or above 
            % overlap save ML algorithm segmentation
            %
            int_results_cols(app);
            %
            % save results
            %
            x = app.q;
            app.overlap_table = x;
            saveLabel(app)
            saveTable(app)
            %
        end
        %
        function crt_int_tbl(app, tt)
            %%
            % intialize the table for the overlap computation
            %%
            cells_n1 = app.cells_n;
            m1 = app.m;
            m1 = cell2mat(m1);
            m_o = m1(1:2:end);
            m_e = m1(2:2:end);
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_X_centroid']) = m_o';
            tbl.([tt,'_Y_centroid']) = m_e';
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, 'ML')
                app.ML_table = tbl;
            elseif strcmp(tt,'IF')
                app.IF_table = tbl;
            end
            %
        end
        %
        function getIFdistinct(app)
            %%
            % get the distinct cell objects from both inform cell
            %  segmentation layers
            %%
            %
            % get distinct objects in inform
            %
            im = app.im_IF(:,:,2);
            IF_cells_1 = label2idx(im);
            m1 = regionprops(im,'centroid');
            m1 = struct2cell(m1);
            %
            im = app.im_IF(:,:,3);
            IF_cells_2 = label2idx(im);
            m2 = regionprops(im,'centroid');
            m2 = struct2cell(m2);
            %
            if length(IF_cells_1) > length(IF_cells_2)
                nn = length(IF_cells_1);
            else
                nn = length(IF_cells_2);
            end
            %
            IF_cellso = cell(nn, 1);
            mo = cell(1,nn);
            %
            ii = ~cellfun(@isempty,IF_cells_1);
            IF_cellso(ii) = IF_cells_1(ii);
            mo(ii) = m1(ii);
            %
            ii = ~cellfun(@isempty,IF_cells_2);
            IF_cellso(ii) = IF_cells_2(ii);
            mo(ii) = m2(ii);
            %
            app.cells_n = IF_cellso;
            app.m = mo;
            %
        end
        %
        function comp_overlap(app)
            %%
            % This function computes the overlaps and adds them to the appropriate rows
            % in the table
            %
            %%
            o = [];
            for level = 1:2
                im1 = app.im_IF(:,:,level+1);
                overlap = im1 > 0 & app.im_ML > 0;
                pairs = [im1(overlap), app.im_ML(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app,idx1,...
                    pairs_cnt(:,2) ,pairs_cnt(:,3), 'IF');
                %
                crt_ovr_tbl(app,idx2,...
                    pairs_cnt(:,1) ,pairs_cnt(:,3), 'ML');
                %
                tbl = outerjoin(app.o_tbl_IF,app.o_tbl_ML,'MergeKeys',1);
                %
                tbl.IF_level = repmat(level, height(tbl),1);
                o = [o;tbl];
            end
            %
            o.joint_overlap = o.IF_frac .* o.ML_frac;
            %
            % find cells with jo < 10% and remove them from the table
            % these cells do not actually overlap well and are probably
            % just beside one another
            %
            ii = o.joint_overlap < .1;
            o(ii,:) = [];
            %
            app.q = o;
            %
        end
        %
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, 'ML')
                tbl_in = app.ML_table;
            elseif strcmp(tt, 'IF')
                tbl_in = app.IF_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_frac']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.cellid = (1:height(tbl))';
            %
            if strcmp(tt, 'ML')
                app.o_tbl_ML = tbl;
            elseif strcmp(tt, 'IF')
                app.o_tbl_IF = tbl;
            end
            %
        end
        %
        function add_no(app)
            %
            tbl = app.q;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            %
            app.IF_table.IF_level = ones(height(app.IF_table), 1);
            im1 = app.im_IF(:,:,3);
            im1 = unique(im1);
            ii = ismember(app.IF_table.IF_cellid, im1);
            app.IF_table.IF_level(ii) = repmat(2, sum(ii), 1);
            %
            IF_tableo = app.IF_table;
            idx1 = unique(IF_tableo.IF_cellid);
            idx2 = unique(tbl.IF_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, IF_tableo, colnames, z);
            %
            z = height(app.q);
            app.ML_table.IF_level = ones(height(app.ML_table), 1);
            ML_tableo = app.ML_table;
            idx1 = unique(ML_tableo.ML_cellid);
            idx2 = unique(tbl.ML_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, ML_tableo, colnames, z);
            %
        end
        %
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells
            % without overlaps
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.cellid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            titles = {'IF','ML'};
            for i1 = 1:2
                if ~ismember([titles{i1},'_cells'], colnames_no)
                    tbl.([titles{i1},'_cells']) = num2cell(...
                        tbl.([titles{i1},'_cells']));
                end
            end
            %
            app.q = [app.q; tbl];
            %
        end
        %
        function rm_edge_cells(app)
            %%
            % this function removes the cells that touch the edges of the 
            % images from the training set
            %%
            x = app.q;
            %
            % build an empty image and get the border pixels
            %
            im_e = zeros(app.nRows, app.nCols);
            im_e(1,1:app.nCols) = ones(1, app.nCols);
            im_e(app.nRows,1:app.nCols) = ones(1, app.nCols);
            im_e(1:app.nRows, 1) = ones(1, app.nRows);
            im_e(1:app.nRows, app.nCols) = ones(1, app.nRows);
            ii = find(im_e);
            %
            % find where either the machine learning or the inform 
            % algorithm defines cells with any pixels touching the edge of 
            % the image
            %
            ML_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.ML_cells);
            IF_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.IF_cells);
            edges_p = ML_edges_p + IF_edges_p;
            %
            % remove those rows from the table
            %
            idx = find(edges_p);
            x(idx,:) = [];
            app.q = x;
            %
        end
        %
        function int_results_cols(app)
            %%
            % this function intializes the results columns and defines 
            % the cells that overlap 80% above as the machine learning 
            % algorithm cell but defines the cell include parameter as 
            % 4 instead of 2
            %
            %%
            x = app.q;
            %
            % results columns
            %
            x.class_selection = zeros(height(x),1);
            x.cell_check = zeros(height(x),1);
            x.flags = zeros(height(x),1);
            %
            c = zeros(height(x),1);
            c = num2cell(c);
            x.final_cells = c;
            %
            % settings for the 80% overlap cells; look over 20% of these
            %
            ii = x.joint_overlap >= .8;
            x.cell_check(~ii) = 1;
            x.class_selection(ii) = 4;
            %
            ii = find(ii);
            n_overlaps = length(ii);
            r = randi([1,n_overlaps], floor(.8 * n_overlaps), 1);
            x.final_cells(ii(r)) = x.ML_cells(ii(r));
            %
            r = ~ismember(r, ii);
            x.cell_check(ii(r)) = 2;
            x.class_selection(ii(r)) = 0; 
            %
            % remove empty cells
            %
            ii = isnan(x.ML_X_centroid);
            x(ii,:) = [];
            ii = isnan(x.IF_X_centroid);
            x(ii,:) = [];
            %
            % redefine the cellid
            %
            x.cellid = (1:height(x))';
            %
            x = x(:,{'IF_cellid','IF_X_centroid','IF_Y_centroid',...
                'IF_paired_w','ML_cellid','ML_X_centroid',...
                'ML_Y_centroid','ML_paired_w','cellid','IF_level',...
                'joint_overlap','class_selection','cell_check',...
                'flags','IF_cells','ML_cells','final_cells'});
            x.Properties.VariableNames = {'IF_cellid','IF_X_centroid',...
                'IF_Y_centroid','ML_paired_w_IF','ML_cellid',...
                'ML_X_centroid','ML_Y_centroid','IF_paired_w_ML',...
                'cellid','IF_level','joint_overlap','class_selection',...
                'cell_check','flags','IF_cells','ML_cells','final_cells'};
            app.q = x;
            %
        end
        %
        function apply_image_colors(app, dapi_logical, mem_logical)
            %%
            % scale the colors according to the slider and color
            % button selection
            %%
            %
            if dapi_logical
                app.a_d = .05 * app.ContrastSlider.Value;
                app.b_d = 4 * app.BrightnessSlider.Value;
                app.im_d =  app.b_d .* asinh(...
                    app.a_d .* app.mem_dapi_im(:,1)) * app.mycols(1,:);
            end
            %
            if mem_logical
                app.a_m = .05 * app.ContrastSlider.Value;
                app.b_m = 4 * app.BrightnessSlider.Value;
            end
            %
            app.im_m =  app.b_m .* asinh(...
                app.a_m .* app.mem_dapi_im(:,2)) * app.mycols(2,:);
            app.im_m = app.im_m + app.im_d;
            %
        end
        %
        function disp_images(app, nm, n1, nall)
            if nm
                ff = app.fname;
                if nargin > 2
                    n1 = num2str(n1);
                    nall = num2str(nall);
                    app.UIFigure.Name = ['Segmentation Tool: ',...
                        ff, '; Cell: ', n1, 'of', nall];
                else
                    app.UIFigure.Name = ['Segmentation Tool: ',ff];
                end
                xl = [1, app.rr - app.ll];
                yl = [1, app.bb - app.tt];
            else
                xl = xlim(app.B_Image);
                yl = ylim(app.B_Image);
            end
            %
            % r = randi([1, 2], 1);
            imshow2(app, app.A_Image, 1, 1,xl,yl)
            imshow2(app, app.A_Image_2, 2, 1,xl,yl)
            %
            imshow2(app, app.B_Image, 1, 2,xl,yl)
            imshow2(app, app.B_Image_2, 2, 2,xl,yl)
            %
            linkaxes([app.A_Image,app.A_Image_2,app.B_Image,app.B_Image_2], 'xy')
            %
        end
        %
        function imshow2(app, loc, layer, s_layer,xl,yl)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            % loc: is the location parameters defined in the startupfnc
            % layer: is 1 for dapi, anything else is the emmbrane and dapi
            % s_layer: The image segmenation to display (IF or ML)
            % xl: x centroid
            % yl: y centroid
            %%
            C = app.nCols;
            R = app.nRows;
            %
            if layer ~= 1
                im = app.im_m;
            else
                im = app.im_d;
            end
            %
            % default is for segmentation to be on; if button is selected
            % then the button value returns 1 don't apply segmentation
            %
            ii = app.ToggleSegButton.Value;
            if ~ii
                if s_layer == 2
                    im_mask = app.IF_c;
                    im2 = app.seg_IF;
                else
                    im_mask = app.ML_c;
                    im2 = app.seg_ML;
                end
                aa = .35;
                if length(im_mask) > 1
                    im(im_mask,:) = (1-aa) .* im(im_mask,:) ...
                        + aa.* im2(im_mask, :);
                end
            end
            im = reshape(im, R, C, 3);
            %
            im = im(app.tt(1):app.bb(1),app.ll(1):app.rr(1),:);
            %
            im = uint8(im);
            %
            imshow(im, 'Parent', loc);
            xlim(loc, xl)
            ylim(loc, yl)
            %
            %xlim(loc, [app.ll, app.rr])
            %ylim(loc, [app.tt, app.bb])
            %
        end
        %
        function getSegmap(app)
            %%
            % The function defines the image cut out region
            %%
            inp = app.n;
            x = app.overlap_table;
            %
            ii = x.IF_cells{inp};
            IF_n_pixels = length(ii);
            im2 = zeros(app.nCols * app.nRows, 3);
            if length(ii) > 1
                im2(ii,1) = 255;
            end
            app.seg_IF = im2;
            app.IF_c = ii;
            %
            ii = x.ML_cells{inp};
            ML_n_pixels = length(ii);
            im2 = zeros(app.nCols * app.nRows, 3);
            if length(ii) > 1
                im2(ii,1) = 255;
            end
            app.seg_ML = im2;
            app.ML_c = ii;
            %
            if IF_n_pixels > ML_n_pixels
                x_center = x.IF_X_centroid(inp);
                y_center = x.IF_Y_centroid(inp);
            else
                x_center = x.ML_X_centroid(inp);
                y_center = x.ML_Y_centroid(inp);
            end
            %
            app.ll = floor(x_center - 50);
            app.rr = floor(x_center + 50);
            app.tt = floor(y_center - 50);
            app.bb = floor(y_center + 50);
            if app.tt < 1
                app.tt = 1;
            end
            if app.bb > app.nRows
                app.bb = app.nRows;
            end
            if app.ll < 1
                app.ll = 1;
            end
            if app.rr > app.nCols
                app.rr = app.nCols;
            end
            %
        end
        %
        function saveProtocol(app)
            %
            if ~isempty(app.n)
                %
                hh1 = findall([app.UIFigure], '-property', 'Enable');
                set(hh1,'Enable', 'off')
                %
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message',...
                    'Saving current comparison image and table');
                app.e = 0;
                saveLabel(app)
                saveTable(app)
                %
                switch app.e
                    case 1
                        uialert(app.UIFigure,['Comparision ',....
                            'image not saved! Check that you have',...
                            ' access to the output location in: ',...
                            app.wd], "Saving Result Error");
                    case 2
                        uialert(app.UIFigure,['Comparision ',....
                            'csv file not saved! Check that you have',...
                            ' access to the output location in: ',...
                            app.wd],"Saving Result Error");
                end
                %
                d.Value = 1;
                pause(.5)
                close(d);
                %
                set(hh1,'Enable', 'on')
                %
            end
            %
        end
        %
        function saveLabel(app)
            %%
            % create and save the 6 layer label matrix from the overlap_table
            % Order is as follows
            % ML immune
            % ML tumor
            % IF immune
            % IF tumor
            % final immune
            % final tumor
            %
            %%
            %
            for level = 1:2
                %
                ii = app.overlap_table.IF_level == level;
                cells_n1 = app.overlap_table.ML_cells(ii);
                im(:, :, level) = createLabelmatrix(app, cells_n1);
                %
                cells_n1 = app.overlap_table.IF_cells(ii);
                im(:,:,level + 2) = createLabelmatrix(app, cells_n1);
                %
                ii = app.overlap_table.IF_level == level ...
                    & app.overlap_table.class_selection == 3;
                cells_n1 = app.overlap_table.final_cells(ii);
                im(:,:,level + 4) = createLabelmatrix(app, cells_n1);
                %
            end
            %
            % set image output properties
            %
            ds.Photometric = Tiff.Photometric.MinIsBlack;
            ds.BitsPerSample   = 16;
            ds.SamplesPerPixel = 1;
            ds.SampleFormat = Tiff.SampleFormat.UInt;
            ds.RowsPerStrip = 41;
            ds.MaxSampleValue = 256;
            ds.MinSampleValue = 0;
            ds.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            ds.Software = 'MATLAB';
            ds.ResolutionUnit = Tiff.ResolutionUnit.Inch;
            ds.XResolution = 300;
            ds.YResolution = 300;
            ds.Compression = Tiff.Compression.LZW;
            ds.ImageLength = app.nRows;
            ds.ImageWidth = app.nCols;
            %
            oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.tif'];
            %
            d = datenum(datetime);
            f = dir(oiname);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            ic = 0;
            while d > f.datenum
                %
                % write image
                %
                ii = Tiff(oiname,'w');
                %
                ii.setTag(ds)
                d = uint16(im(:,:,1));
                write(ii,d);
                %
                for i3 = 2:(size(im, 3))
                    writeDirectory(ii)
                    d = uint16(im(:,:,i3));
                    ii.setTag(ds);
                    write(ii,d);
                end
                %
                close(ii)
                %
                f = dir(oiname);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                ic = ic + 1;
                if ic == 5
                    app.e = 1;
                    break
                end
                %
            end
        end
        %
        function L = createLabelmatrix(app, cells_n1)
            %%
            % create a label matrix L from the cells in cells_n
            %
            %%
            %
            L = zeros(app.nRows, app.nCols, 'single');
            %
            for i1 = 1:length(cells_n1)
                idx = cells_n1{i1};
                if length(idx) == 1
                    continue
                end
                L(idx) = i1;
            end
            %
        end
        %
        function saveTable(app)
            %%
            % This function saves the app.overlap_table as a .csv file without the
            % cells in it
            %
            %%
            x = app.overlap_table;
            %
            x.final_cells = [];
            x.IF_cells = [];
            x.ML_cells = [];
            %
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            %
            d = datenum(datetime);
            f = dir(ofname);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            %
            ic = 0;
            while d > f.datenum
                writetable(x, ofname)
                %
                pause(.5)
                f = dir(ofname);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                ic = ic + 1;
                if ic == 5
                    app.e = 2;
                    break
                end
            end
            %
        end
        %
        function reload_overlap_table(app)
            %%
            % this function reads in the comparison seg data and label matrix then
            % rearranges to the overlap_table
            %
            %%
            app.pgbar.Value = .61;
            app.pgbar.Message = ['Previous comparison files found ',...
                '... loading into memory'];
            pause(.5)
            %
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            x = readtable(ofname);
            %
            % convert to new format if there are too many columns
            %
            if width(x) ~= 14
                x.class_selection = x.include_cell;
                x.joint_overlap = x.jp;
                x.cell_check = zeros(height(x),1);
                x.flags = zeros(height(x),1);
                %
                % settings for the 80% overlap cells; look over 20% of these
                %
                ii = x.joint_overlap >= .8;
                x.cell_check(~ii) = 1;
                x.class_selection(ii) = 4;
                %
                ii = find(ii);
                n_overlaps = length(ii);
                r = randi([1,n_overlaps], floor(.8 * n_overlaps), 1);
                %
                r = ~ismember(r, ii);
                x.cell_check(ii(r)) = 2;
                x.class_selection(ii(r)) = 0;
                %
                x = x(:,{'IF_cellid','IF_X_centroid','IF_Y_centroid',...
                    'IF_paired_w','ML_cellid','ML_X_centroid',...
                    'ML_Y_centroid','ML_paired_w','cellid','IF_level',...
                    'joint_overlap','class_selection','cell_check',...
                    'flags'});
                x.Properties.VariableNames = {'IF_cellid','IF_X_centroid',...
                    'IF_Y_centroid','ML_paired_w_IF','ML_cellid',...
                    'ML_X_centroid','ML_Y_centroid','IF_paired_w_ML',...
                    'cellid','IF_level','joint_overlap','class_selection',...
                    'cell_check','flags'};
            end
            %
            oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.tif'];
            im = zeros(app.nRows, app.nCols, 6);
            for i1 = 1:6
                im(:,:,i1) = imread(oiname, i1);
            end
            %
            x.ML_cells = repmat({0},height(x),1);
            x.IF_cells = repmat({0},height(x),1);
            x.final_cells = repmat({0},height(x),1);
            %
            for level = 1:2
                %
                ii = x.IF_level == level;
                L = im(:,:,level);
                cells_n1 = x.ML_cellid(ii);
                %
                [~,iY,~] = unique(cells_n1, 'last');
                for i1 = 1:length(iY)
                    c = find(L == iY(i1));
                    ii = x.ML_cellid == cells_n1(iY(i1));
                    x.ML_cells(ii) = {c};
                end
                %
                ii = x.IF_level == level;
                L = im(:,:,level + 2);
                cells_n1 = x.IF_cellid(ii);
                %
                [~,iY,~] = unique(cells_n1, 'last');
                for i1 = 1:length(iY)
                    c = find(L == iY(i1));
                    ii = x.IF_cellid == cells_n1(iY(i1));
                    x.IF_cells(ii) = {c};
                end
                %
                ii = x.IF_level == level & x.class_selection == 3;
                L = im(:,:,level + 4);
                cells_n1 = x.cellid(ii);
                %
                Y = unique(cells_n1);
                for i1 = 1:length(Y)
                    c = find(L == i1);
                    x.final_cells(Y(i1)) = {c};
                end
                %
            end
            %
            ii = x.class_selection == 2 | x.class_selection == 4;
            x.final_cells(ii) = x.ML_cells(ii);
            %
            ii = x.class_selection == 1;
            x.final_cells(ii) = x.IF_cells(ii);
            %
            app.overlap_table = x;
            %
        end
        %
        function chgColors(app,hnd,step)
            %
            switch step
                case 'on'
                    hnd.BackgroundColor = 'g';
                case 'off'
                    hnd.BackgroundColor = [.90 .90 .90];
                    hnd.Value = 0;
            end
            %
        end
        %
        end
    methods (Access = public)
        %
        function returnDialog(app, closing_opts)
            app.closing_opts = closing_opts;
            switch app.closing_opts
                case 1
                    delete(app)
                case 2
                    app.e = 0;
                    saveProtocol(app)
                    if app.e == 0
                        delete(app)
                    else
                        set(app.hh2, 'Enable','on')
                    end
                otherwise
                    set(app.hh2, 'Enable','on')
            end
        end
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % Configure image axes
            %
            app.A_Image = axes('Parent',app.Images);
            app.A_Image.Box = 'on';
            app.A_Image.XTick = [];
            app.A_Image.XTickLabel = [];
            app.A_Image.YTick = [];
            app.A_Image.ZTick = [];
            app.A_Image.Color = [1 1 1];
            app.A_Image.Units = 'pixels';
            app.A_Image.Position = [11 297 281 281];
            app.A_Image.Visible = 'on';
            app.A_Image.Colormap = gray(256);
            axis(app.A_Image, 'image');
            %
            app.A_Image_2 = axes('Parent',app.Images);
            app.A_Image_2.Box = 'on';
            app.A_Image_2.XTick = [];
            app.A_Image_2.XTickLabel = [];
            app.A_Image_2.YTick = [];
            app.A_Image_2.ZTick = [];
            app.A_Image_2.Color = [1 1 1];
            app.A_Image_2.Units = 'pixels';
            app.A_Image_2.Position = [11 12 281 281];
            app.A_Image_2.Visible = 'on';
            app.A_Image_2.Colormap = gray(256);
            axis(app.A_Image_2, 'image');
            %
            app.B_Image = axes('Parent',app.Images);
            app.B_Image.Box = 'on';
            app.B_Image.XTick = [];
            app.B_Image.XTickLabel = [];
            app.B_Image.YTick = [];
            app.B_Image.ZTick = [];
            app.B_Image.Color = [1 1 1];
            app.B_Image.Units = 'pixels';
            app.B_Image.Position = [298 297 281 281];
            app.B_Image.Visible = 'on';
            app.B_Image.Colormap = gray(256);
            axis(app.B_Image, 'image');
            %
            app.B_Image_2 = axes('Parent',app.Images);
            app.B_Image_2.Box = 'on'; 
            app.B_Image_2.XTick = [];
            app.B_Image_2.XTickLabel = [];
            app.B_Image_2.YTick = [];
            app.B_Image_2.ZTick = [];
            app.B_Image_2.Color = [1 1 1];
            app.B_Image_2.Units = 'pixels';
            app.B_Image_2.Position = [298 12 281 281];
            app.B_Image_2.Visible = 'on';
            app.B_Image_2.Colormap = gray(256);
            axis(app.B_Image_2, 'image');
            %
            app.ContrastSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            %
            app.closing_opts = 1;
            app.cc = 1;
            % Update the image and histograms
            % updateimage(app, 'peppers.png');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %    
            % if app.n is not empty then there are results and they should
            % be saved before moving on to next image
            %
            app.LoadButton.BackgroundColor = 'g';
            app.LoadButton.BackgroundColor = [.90 .90 .90];
            saveProtocol(app)
            %
            hh = findall([app.UIFigure], '-property', 'Enable');
            set(hh,'Enable', 'off')
            %
            % Display uigetfile dialog
            %
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if isequal(f,0)
                uialert(app.UIFigure,['User selected Cancel.',...
                   ' Please select a valid input file'],'Invalid File')
                set(hh,'Enable', 'on')
                figure(app.UIFigure)
                return
            end
            figure(app.UIFigure)
            %
            app.pgbar = uiprogressdlg(app.UIFigure,'Title',...
                'Please Wait','Message',['checking file names ',...
                    'and file structure']);
            %
            imagefile = [p f];
            updateimage(app, imagefile);
            %
            app.pgbar.Value = 1;
            app.pgbar.Message = 'Finishing';
            pause(.5)
            close(app.pgbar);
            set(hh,'Enable', 'on')
            %
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %
            if ~isempty(app.n)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %
            if ~isempty(app.n)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ToggleSegButton
        function ToggleSegButtonValueChanged(app, event)
            %
            if app.ToggleSegButton.Value
                chgColors(app,app.AppliedSegButton,'off')
                chgColors(app,app.ToggleSegButton,'on')
            else
                chgColors(app,app.ToggleSegButton,'off')
            end
            %
            if ~isempty(app.n)    
                disp_images(app, 0)
            end
            %
        end

        % Button pushed function: backOnecellButton
        function backOnecellButtonPushed(app, event)
            %
            if ~isempty(app.n) && app.n > 1
                ii = 4;
                %
                while ii == 4
                        app.n = app.n - 1;
                     ii = app.overlap_table.class_selection(app.n);
                end
                %
                app.cc = 1;
                %
                % reset segmentation mask
                %
                app.ToggleSegButton.Value = false;
                %
                % set the radio button to appropriate value
                %
                if ii == 1
                    app.SelectInFormSegmentationButton.Value = true;
                elseif ii == 2
                    app.SelectSuperpixelSegmentationButton.Value = true;
                elseif ii == -1
                    app.RejectCellButton.Value = true;
                else
                    app.DrawDAPIButton.Value = true;
                end
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table));
            end
            %
        end

        % Button pushed function: NextCellButton
        function NextCellButtonPushed(app, event)
            %
            if ~isempty(app.n)
                %
                if app.cc == 0
                    %
                    % sets the default value for the cell if no changes
                    % have taken place
                    %
                    inp = app.n;
                    app.overlap_table.class_selection(inp) = 2;
                    app.overlap_table.final_cells(inp) = app.overlap_table.ML_cells(inp);
                end
                app.n = find(app.overlap_table.class_selection == 0, 1);
                app.cc = 0;
                %
                % reset segmentation mask
                %
                app.ToggleSegButton.Value = false;
                % 
                % get the segmentation mask and set it to display the new
                % images
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table))
                %
                % resets to ML
                %
                app.SelectSuperpixelSegmentationButton.Value = true;
                %
            end
            %
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            %
            %hh2 = findall(app.SaveTableButton, '-property', 'Enable');
            set(app.SaveTableButton,'Enable', 'off')
            saveProtocol(app)
            set(app.SaveTableButton,'Enable', 'on')
            %
        end

        % Button pushed function: JumptocellButton
        function JumptocellButtonPushed(app, event)
            %
            if ~isempty(app.n)
                %
                inp = app.JumpCellButton.Value;
                app.cc = 1;
                if inp > 0 && inp < height(app.overlap_table)
                    %
                    app.n = inp;
                    %
                    % reset segmentation mask
                    %
                    app.ToggleSegButton.Value = false;
                    %
                    % set the radio button to appropriate value
                    %
                    ii = app.overlap_table.class_selection(app.n);
                    if ii == 1
                        app.SelectInFormSegmentationButton.Value = true;
                    elseif ii == 2 || ii == 4 || ii == 0
                        app.SelectSuperpixelSegmentationButton.Value = true;
                    elseif ii == -1
                        app.RejectCellButton.Value = true;
                    else
                        app.DrawDAPIButton.Value = true;
                    end
                    %
                    getSegmap(app)
                    disp_images(app, 1, app.n, height(app.overlap_table));
                    %
                end
                %
            end
            %
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %
            app.hh2 = findall([app.UIFigure], '-property', 'Enable');
            set(app.hh2,'Enable', 'off')
            %
            if ~isempty(app.n)
                app.ClosingDialogApp = ClosingDialog(app);
                waitfor(app.ClosingDialogApp)
            else
                delete(app)
            end   
            %
        end

        % Button pushed function: forwardOnecellButton
        function forwardOnecellButtonPushed(app, event)
            %
            if ~isempty(app.n) && app.n > 1
                ii = 4;
                %
                while ii == 4
                    app.n = app.n + 1;
                    ii = app.overlap_table.class_selection(app.n);
                end
                %
                app.cc = 1;
                %
                % reset segmentation mask
                %
                app.ToggleSegButton.Value = false;
                %
                % set the radio button to appropriate value
                %
                if ii == 1
                    app.SelectInFormSegmentationButton.Value = true;
                elseif ii == 2
                    app.SelectSuperpixelSegmentationButton.Value = true;
                elseif ii == -1
                    app.RejectCellButton.Value = true;
                else
                    app.DrawDAPIButton.Value = true;
                end
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table));
            end
            %
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            %
            % release a draw object if exists
            %{
            if ~isempty(app.cell_obj)
                delete(app.cell_obj);
            end
            %}
            % update chart for A
            %
            if ~isempty(app.n)
                inp = app.n;
                app.overlap_table.class_selection(inp) = 2;
                app.overlap_table.final_cells(inp) =...
                    app.overlap_table.ML_cells(inp);
            end
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            if app.DAPIButton.Value
                chgColors(app,app.MemButton,'off')
                chgColors(app,app.DAPIButton,'on')
                app.ContrastSlider.Value = app.a_d /.05;
                app.BrightnessSlider.Value = app.b_d / 4;
            else
                chgColors(app,app.DAPIButton,'off')               
            end
            %
        end

        % Value changed function: MemButton
        function MemButtonValueChanged(app, event)
            %
            if app.MemButton.Value
                chgColors(app,app.DAPIButton,'off')
                chgColors(app,app.MemButton,'on')
                app.ContrastSlider.Value = app.a_m /.05;
                app.BrightnessSlider.Value = app.b_m / 4;
            else
                chgColors(app,app.MemButton,'off')
            end
            %
        end

        % Value changed function: AppliedSegButton
        function AppliedSegButtonValueChanged(app, event)
            %
            if app.AppliedSegButton.Value
                chgColors(app,app.ToggleSegButton,'off')
                chgColors(app,app.AppliedSegButton,'on')
            else
                chgColors(app,app.AppliedSegButton,'off')
            end
            %
            if ~isempty(app.n)    
                disp_images(app, 0)
            end
            %
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.Selections = uipanel(app.UIFigure);
            app.backOnecellButton = uibutton(app.Selections, 'push');
            app.backOnecellButton.ButtonPushedFcn = createCallbackFcn(app, @backOnecellButtonPushed, true);
            app.backOnecellButton.Text = '<';
            app.NextCellButton = uibutton(app.Selections, 'push');
            app.NextCellButton.ButtonPushedFcn = createCallbackFcn(app, @NextCellButtonPushed, true);
            app.NextCellButton.Text = 'Next Cell';
            app.JumptocellButton = uibutton(app.Selections, 'push');
            app.JumptocellButton.ButtonPushedFcn = createCallbackFcn(app, @JumptocellButtonPushed, true);
            app.JumptocellButton.Text = 'Jump to cell:';
            app.JumpCellButton = uieditfield(app.Selections, 'numeric');
            app.JumpCellButton.ValueDisplayFormat = '%.0f';
            app.forwardOnecellButton = uibutton(app.Selections, 'push');
            app.forwardOnecellButton.ButtonPushedFcn = createCallbackFcn(app, @forwardOnecellButtonPushed, true);
            app.forwardOnecellButton.Text = '>';
            app.Panel_5 = uipanel(app.Selections);
            app.ABButton = uibutton(app.Panel_5, 'push');
            app.ABButton.Text = 'A = B';
            app.AButton = uibutton(app.Panel_5, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.Panel_5, 'push');
            app.BButton.Text = 'B';
            app.NeitherButton = uibutton(app.Panel_5, 'push');
            app.NeitherButton.Text = 'Neither';
            app.ArtifactButton = uibutton(app.Panel_5, 'push');
            app.ArtifactButton.Text = 'Artifact';
            app.DrawonDAPIButton = uibutton(app.Panel_5, 'push');
            app.DrawonDAPIButton.Text = 'Draw on DAPI';
            app.DrawonMemButton = uibutton(app.Panel_5, 'push');
            app.DrawonMemButton.Text = 'Draw on Mem';
            app.ClassificationLabel = uilabel(app.Panel_5);
            app.ClassificationLabel.Text = 'Classification';
            app.FlagButton = uibutton(app.Panel_5, 'push');
            app.FlagButton.Text = 'Flag';
            app.MultiNucButton = uibutton(app.Panel_5, 'push');
            app.MultiNucButton.Text = 'Multi Nuc';
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '________________________________';
            app.Display = uipanel(app.Selections);
            app.ContrastSliderLabel = uilabel(app.Display);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ToggleSegButton = uibutton(app.Display, 'state');
            app.ToggleSegButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegButtonValueChanged, true);
            app.ToggleSegButton.Text = 'Toggle Seg';
            app.ContrastSlider = uislider(app.Display);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.BrightnessSliderLabel = uilabel(app.Display);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Display);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.DisplayLabel = uilabel(app.Display);
            app.DisplayLabel.Text = 'Display';
            app.AppliedSegButton = uibutton(app.Display, 'state');
            app.AppliedSegButton.ValueChangedFcn = createCallbackFcn(app, @AppliedSegButtonValueChanged, true);
            app.AppliedSegButton.Text = 'Applied Seg';
            app.DAPIButton = uibutton(app.Display, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Text = 'DAPI';
            app.MemButton = uibutton(app.Display, 'state');
            app.MemButton.ValueChangedFcn = createCallbackFcn(app, @MemButtonValueChanged, true);
            app.MemButton.Text = 'Mem';
            app.LoadButton = uibutton(app.Selections, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load new image ';
            app.SaveTableButton = uibutton(app.Selections, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.Images = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end